classdef fase2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MenuDropDown             matlab.ui.control.DropDown
        MenuDropDownLabel        matlab.ui.control.Label
        PlayButton               matlab.ui.control.Button
        BandWidthHzKnob          matlab.ui.control.DiscreteKnob
        BandWidthHzLabel         matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        Switch_2                 matlab.ui.control.Switch
        Switch                   matlab.ui.control.Switch
        BandWidthHzKnob_2        matlab.ui.control.DiscreteKnob
        BandWidthHzLabel_2       matlab.ui.control.Label
        BandWidthHzKnob_3        matlab.ui.control.DiscreteKnob
        BandWidthHzLabel_3       matlab.ui.control.Label
        CenterFreqHzKnob_2       matlab.ui.control.DiscreteKnob
        CenterFreqHzKnob_2Label  matlab.ui.control.Label
        CenterFreqHzKnob_3       matlab.ui.control.DiscreteKnob
        CenterFreqHzKnob_3Label  matlab.ui.control.Label
        GaindBKnob_2             matlab.ui.control.DiscreteKnob
        GaindBKnob_2Label        matlab.ui.control.Label
        GaindBKnob_3             matlab.ui.control.DiscreteKnob
        GaindBKnob_3Label        matlab.ui.control.Label
        GaindBKnob               matlab.ui.control.DiscreteKnob
        GaindBKnobLabel          matlab.ui.control.Label
        CenterFreqHzKnob         matlab.ui.control.DiscreteKnob
        CenterFreqHzLabel        matlab.ui.control.Label
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        variablesGlobals 
        frequenciaCentral1 
        frequenciaCentral2
        frequenciaCentral3
        bandWidth1
        bandWidth2
        bandWidth3
        gain1
        gain2
        gain3
        audioCarregat
        fs
        valueSwitch
        valueSwitch2
        B
        A
        senyalFiltrada
        n
    end
    
    methods (Access = private)
        
        function plotSignals(app)
            delta = [1 zeros(1,length(app.audioCarregat)-1)];
            h = filter(app.B,app.A,delta);
            rootB = roots(app.B);
            rootA = roots(app.A);
            plot(app.UIAxes3,0:100,exp(1i*(2*pi/100)*(0:100)));
            hold on
            plot(app.UIAxes3,rootB,'rx');
            plot(app.UIAxes3,rootA,'bo');
            hold off 
           
            switch(app.valueSwitch)

                case"Frequency"
                    mostraFs = round(length(app.audioCarregat)/2);
                    [H,f] = freqz(app.B,app.A,app.n,app.fs);
                    X = fft(app.audioCarregat);
                    Y = fft(app.senyalFiltrada);
                    semilogx(app.UIAxes,f,20*log10(abs(H)));
                    semilogx(app.UIAxes2,flog(1:mostraFs),20*log10(abs(X(1:mostraFs)))); %mirar si pot axis
                    hold on
                    semilogx(app.UIAxes2,flog(1:mostraFs),20*log10(abs(Y(1:mostraFs)))); %mirar si pot axis
                    hold off
                case"Time"   
                    plot(app.UIAxes2,length(app.audioCarregat)-1,app.audioCarregat);
                    hold on
                    plot(app.UIAxes2,length(app.senyalFiltrada)-1,app.senyalFiltrada);
                    hold off
                    plot(app.UIAxes,0:99,h(1:100));
                    
            end        
        end
        %funcio getCoeficients
        function getCoeficients(app)
            [B1,A1] = parameq(app.fs,app.frequenciaCentral1,app.bandWidth1,app.gain1);
            [B2,A2] = parameq(app.fs,app.frequenciaCentral2,app.bandWidth2,app.gain2);
            [B3,A3] = parameq(app.fs,app.frequenciaCentral3,app.bandWidth3,app.gain3);
            
            app.B = conv(B3,conv(B1,B2));
            app.A = conv(A3,conv(A1,A2));
        end
        %Funció filterEqu
        function filterEqu(app)
            app.senyalFiltrada = filter(app.B,app.A,app.audioCarregat);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.n = 1024;
            [app.audioCarregat,app.fs] = audioread('speech_dft.wav');
            
            app.frequenciaCentral1 = app.CenterFreqHzKnob.Value;
            app.frequenciaCentral2 = app.CenterFreqHzKnob_2.Value;
            app.frequenciaCentral3= app.CenterFreqHzKnob_3.Value;
            
            app.gain1 = app.GaindBKnob.Value;
            app.gain2 = app.GaindBKnob_2.Value;
            app.gain3 = app.GaindBKnob_3.Value;

            app.bandWidth1 =  app.BandWidthHzKnob.Value;
            app.bandWidth2 =  app.BandWidthHzKnob_2.Value;
            app.bandWidth3 =  app.BandWidthHzKnob_3.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.valueSwitch1 = app.Switch.Value;
            plotSignals(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            soundsc(app.audioCarregat,app.fs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear soundsc;
        end

        % Value changed function: BandWidthHzKnob
        function BandWidthHzKnobValueChanged(app, event)
          app.bandWidth1   = app.BandWidthHzKnob.Value;
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.valueSwitch2 = app.Switch_2.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: BandWidthHzKnob_2
        function BandWidthHzKnob_2ValueChanged(app, event)
            app.bandWidth2 = app.BandWidthHzKnob_2.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: BandWidthHzKnob_3
        function BandWidthHzKnob_3ValueChanged(app, event)
            app.bandWidth3 = app.BandWidthHzKnob_3.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: CenterFreqHzKnob_2
        function CenterFreqHzKnob_2ValueChanged(app, event)
            app.frequenciaCentral2 = app.CenterFreqHzKnob_2.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: CenterFreqHzKnob_3
        function CenterFreqHzKnob_3ValueChanged(app, event)
            app.frequenciaCentral3 = app.CenterFreqHzKnob_3.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: GaindBKnob_2
        function GaindBKnob_2ValueChanged(app, event)
            app.gain2 = app.GaindBKnob_2.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: GaindBKnob_3
        function GaindBKnob_3ValueChanged(app, event)
            app.gain3 = app.GaindBKnob_3.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: GaindBKnob
        function GaindBKnobValueChanged(app, event)
            app.gain1 = app.GaindBKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: CenterFreqHzKnob
        function CenterFreqHzKnobValueChanged(app, event)
            app.frequenciaCentral1 = app.CenterFreqHzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'Frequency[Hz]')
            ylabel(app.UIAxes3, 'GaindB')
            zlabel(app.UIAxes3, 'Z')
            app.CenterFreqHzLabel = uilabel(app.UIFigure);
            app.CenterFreqHzLabel.Text = 'Center Freq[Hz]';
            app.CenterFreqHzKnob = uiknob(app.UIFigure, 'discrete');
            app.CenterFreqHzKnob.Items = {'100', '1080', '2060', '3040', '4020', '5000'};
            app.CenterFreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @CenterFreqHzKnobValueChanged, true);
            app.CenterFreqHzKnob.Value = '100';
            app.GaindBKnobLabel = uilabel(app.UIFigure);
            app.GaindBKnobLabel.Text = 'Gain [dB]';
            app.GaindBKnob = uiknob(app.UIFigure, 'discrete');
            app.GaindBKnob.Items = {'-90', '-70', '-50', '-30', '-10', '10'};
            app.GaindBKnob.ValueChangedFcn = createCallbackFcn(app, @GaindBKnobValueChanged, true);
            app.GaindBKnob.Value = '-90';
            app.GaindBKnob_3Label = uilabel(app.UIFigure);
            app.GaindBKnob_3Label.Text = 'Gain [dB]';
            app.GaindBKnob_3 = uiknob(app.UIFigure, 'discrete');
            app.GaindBKnob_3.Items = {'-90', '-70', '-50', '-30', '-10', '10'};
            app.GaindBKnob_3.ValueChangedFcn = createCallbackFcn(app, @GaindBKnob_3ValueChanged, true);
            app.GaindBKnob_3.Value = '-90';
            app.GaindBKnob_2Label = uilabel(app.UIFigure);
            app.GaindBKnob_2Label.Text = 'Gain [dB]';
            app.GaindBKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.GaindBKnob_2.Items = {'-90', '-70', '-50', '-30', '-10', '10'};
            app.GaindBKnob_2.ValueChangedFcn = createCallbackFcn(app, @GaindBKnob_2ValueChanged, true);
            app.GaindBKnob_2.Value = '-90';
            app.CenterFreqHzKnob_3Label = uilabel(app.UIFigure);
            app.CenterFreqHzKnob_3Label.Text = 'Center Freq[Hz]';
            app.CenterFreqHzKnob_3 = uiknob(app.UIFigure, 'discrete');
            app.CenterFreqHzKnob_3.Items = {'100', '1080', '2060', '3040', '4020', '5000'};
            app.CenterFreqHzKnob_3.ValueChangedFcn = createCallbackFcn(app, @CenterFreqHzKnob_3ValueChanged, true);
            app.CenterFreqHzKnob_3.Value = '100';
            app.CenterFreqHzKnob_2Label = uilabel(app.UIFigure);
            app.CenterFreqHzKnob_2Label.Text = 'Center Freq[Hz]';
            app.CenterFreqHzKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.CenterFreqHzKnob_2.Items = {'100', '1080', '2060', '3040', '4020', '5000'};
            app.CenterFreqHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @CenterFreqHzKnob_2ValueChanged, true);
            app.CenterFreqHzKnob_2.Value = '100';
            app.BandWidthHzLabel_3 = uilabel(app.UIFigure);
            app.BandWidthHzLabel_3.Text = 'BandWidth [Hz]';
            app.BandWidthHzKnob_3 = uiknob(app.UIFigure, 'discrete');
            app.BandWidthHzKnob_3.Items = {'0', '400', '800', '1200', '1600', '2000'};
            app.BandWidthHzKnob_3.ValueChangedFcn = createCallbackFcn(app, @BandWidthHzKnob_3ValueChanged, true);
            app.BandWidthHzKnob_3.Value = '0';
            app.BandWidthHzLabel_2 = uilabel(app.UIFigure);
            app.BandWidthHzLabel_2.Text = 'BandWidth [Hz]';
            app.BandWidthHzKnob_2 = uiknob(app.UIFigure, 'discrete');
            app.BandWidthHzKnob_2.Items = {'0', '400', '800', '1200', '1600', '2000'};
            app.BandWidthHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @BandWidthHzKnob_2ValueChanged, true);
            app.BandWidthHzKnob_2.Value = '0';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Frequency', 'Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Frequency';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Original', 'Filtered'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Original';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.BandWidthHzLabel = uilabel(app.UIFigure);
            app.BandWidthHzLabel.Text = 'BandWidth [Hz]';
            app.BandWidthHzKnob = uiknob(app.UIFigure, 'discrete');
            app.BandWidthHzKnob.Items = {'0', '400', '800', '1200', '1600', '2000'};
            app.BandWidthHzKnob.ValueChangedFcn = createCallbackFcn(app, @BandWidthHzKnobValueChanged, true);
            app.BandWidthHzKnob.Value = '0';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.MenuDropDownLabel = uilabel(app.UIFigure);
            app.MenuDropDownLabel.Text = 'Menu';
            app.MenuDropDown = uidropdown(app.UIFigure);
            app.MenuDropDown.Items = {'Carregar fitxer', 'Guardar fitxer'};
            app.MenuDropDown.Value = 'Carregar fitxer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fase2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end