classdef fase2okay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        CarregarFitxerMenu      matlab.ui.container.Menu
        GuardarFitxerMenu       matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        Bandwith3HzKnob         matlab.ui.control.Knob
        Bandwith3HzKnobLabel    matlab.ui.control.Label
        Gain3dBKnob             matlab.ui.control.Knob
        Gain3dBKnobLabel        matlab.ui.control.Label
        CenterFreq3HzKnob       matlab.ui.control.Knob
        CenterFreq3HzKnobLabel  matlab.ui.control.Label
        Gain2dBKnob             matlab.ui.control.Knob
        Gain2dBKnobLabel        matlab.ui.control.Label
        Bandwith2HzKnob         matlab.ui.control.Knob
        CenterFreqHzKnob        matlab.ui.control.Knob
        CenterFreqHzKnobLabel   matlab.ui.control.Label
        Bandwith2HzKnobLabel    matlab.ui.control.Label
        CenterFreq2HzKnob       matlab.ui.control.Knob
        CenterFreq2HzKnobLabel  matlab.ui.control.Label
        BandwidthHzKnob         matlab.ui.control.Knob
        BandwidthHzKnobLabel    matlab.ui.control.Label
        GaindBKnob_1            matlab.ui.control.Knob
        GaindBKnob_4Label       matlab.ui.control.Label
        PlayButton              matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        Switch_2                matlab.ui.control.Switch
        Switch                  matlab.ui.control.Switch
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        variablesGlobals 
        frequenciaCentral1 
        frequenciaCentral2
        frequenciaCentral3
        bandWidth1
        bandWidth2
        bandWidth3
        gain1
        gain2
        gain3
        audioCarregat
        fs
        valueSwitch
        valueSwitch2
        B
        A
        senyalFiltrada
        n
        menuvalue
        fsOriginal
    end
    
    methods (Access = private)
        
        function plotSignals(app)
            delta = [1 zeros(1,length(app.audioCarregat)-1)];
            h = filter(app.B,app.A,delta);
            rootB = roots(app.B);
            rootA = roots(app.A);

            cla(app.UIAxes3,'reset');
            plot(app.UIAxes3,real(exp(1i*(2*pi/100)*(0:100))),imag(exp(1i*(2*pi/100)*(0:100))),'--g');grid; axis tight;
          
            title(app.UIAxes3, 'Zero-Pole Plot');
            hold (app.UIAxes3,'on');
            plot(app.UIAxes3,rootB,'rx');
            plot(app.UIAxes3,rootA,'bo');
            hold (app.UIAxes3,'off');
            switch (app.valueSwitch)
                
                case "Frequency"
                    flog = app.fs*(0:length(app.audioCarregat)-1)/length(app.audioCarregat);
                    mostraFs = round(length(app.audioCarregat)/2);

                    [H,f] = freqz(app.B,app.A,app.n,app.fs);
                    X = fft(app.audioCarregat);
                    Y = fft(app.senyalFiltrada);
                    cla(app.UIAxes,'reset');
                    semilogx(app.UIAxes,f,20*log10(abs(H)));
                    xlabel(app.UIAxes, 'Frequency (hz)');
                    ylabel(app.UIAxes, 'Gain (dB)');
                    title(app.UIAxes, 'Filterbank Gain');
                    ylim(app.UIAxes, 'auto');
                    xlim(app.UIAxes, 'auto');


                    cla(app.UIAxes2,'reset');
                    semilogx(app.UIAxes2,flog(1:mostraFs),20*log10(abs(X(1:mostraFs)))); %mirar si pot axis
                    ylim(app.UIAxes2, 'auto');
                    xlim(app.UIAxes2, 'auto');
                    xlabel(app.UIAxes2, 'Frequency (hz)');
                    ylabel(app.UIAxes2, 'Magnitude (dB)');
                    title(app.UIAxes2, 'Signals Spectrum');
                    hold (app.UIAxes2,'on');
                    semilogx(app.UIAxes2,flog(1:mostraFs),20*log10(abs(Y(1:mostraFs)))); %mirar si pot axis
                    hold (app.UIAxes2,'off');
                    legend(app.UIAxes2, 'original', 'filtrada');

                case "Time"   
                    cla(app.UIAxes2,'reset');
                    plot(app.UIAxes2, app.audioCarregat);
                    axis tight;
                    xlabel(app.UIAxes2, 'Time (s)');
                    ylabel(app.UIAxes2, 'Amplitude');
                    title(app.UIAxes2, 'Impulse Response');
                    hold (app.UIAxes2,'on');
                    plot(app.UIAxes2,app.senyalFiltrada);
                    ylim(app.UIAxes2, 'auto');
                    xlim(app.UIAxes2, 'auto');
                    hold (app.UIAxes2,'off');
                    legend(app.UIAxes2, 'original', 'filtrada');


                    cla(app.UIAxes,'reset');
                    plot(app.UIAxes,0:99,h(1:100));
                    ylim(app.UIAxes, 'auto');
                    xlim(app.UIAxes, 'auto');
                    xlabel(app.UIAxes, 'Time (s)');
                    ylabel(app.UIAxes, 'Amplitude');
                    title(app.UIAxes, 'Signals Waveform');  

                    
            end        
        end
        %funcio getCoeficients
        function getCoeficients(app)
            
            [B1,A1] = parameq(app.fs,app.frequenciaCentral1,app.bandWidth1,app.gain1);
            [B2,A2] = parameq(app.fs,app.frequenciaCentral2,app.bandWidth2,app.gain2);
            [B3,A3] = parameq(app.fs,app.frequenciaCentral3,app.bandWidth3,app.gain3);
            
            app.B = conv(B3,conv(B1,B2));
            app.A = conv(A3,conv(A1,A2));
        end
        %Funció filterEqu
        function filterEqu(app)
            app.senyalFiltrada = filter(app.B,app.A,app.audioCarregat);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fs = 22050;
            app.n = 1024;
            
            [app.audioCarregat,app.fsOriginal] = audioread('audio_amb_soroll_3.wav');
            app.audioCarregat = resample(app.audioCarregat,app.fs,app.fsOriginal);
            app.frequenciaCentral1 = app.CenterFreqHzKnob.Value;
            app.frequenciaCentral2 = app.CenterFreq2HzKnob.Value;
            app.frequenciaCentral3= app.CenterFreq3HzKnob.Value;
            
            app.gain1 = app.GaindBKnob_1.Value;
            app.gain2 = app.Gain2dBKnob.Value;
            app.gain3 = app.Gain3dBKnob.Value;
            app.valueSwitch = app.Switch.Value;
            app.valueSwitch2 = app.Switch_2.Value;
            app.bandWidth1 =  app.BandwidthHzKnob.Value;
            app.bandWidth2 =  app.Bandwith2HzKnob.Value;
            app.bandWidth3 =  app.Bandwith3HzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.valueSwitch = app.Switch.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            app.valueSwitch2 = cast(app.valueSwitch2,"like",app.valueSwitch);
            
            switch (app.valueSwitch2)
                case "Original"
                    soundsc(app.audioCarregat,app.fs);
                case "Filtered"
                    soundsc(app.senyalFiltrada,app.fs);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.valueSwitch2 = app.Switch_2.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: GaindBKnob_1
        function GaindBKnob_1ValueChanged(app, event)
            app.gain1 = app.GaindBKnob_1.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: BandwidthHzKnob
        function BandwidthHzKnobValueChanged(app, event)
             app.bandWidth1 = app.BandwidthHzKnob.Value;
             getCoeficients(app);
             filterEqu(app);
             plotSignals(app);
        end

        % Value changed function: CenterFreqHzKnob
        function CenterFreqHzKnobValueChanged(app, event)
            app.frequenciaCentral1 = app.CenterFreqHzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: CenterFreq2HzKnob
        function CenterFreq2HzKnobValueChanged(app, event)
            app.frequenciaCentral2 = app.CenterFreq2HzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: Bandwith2HzKnob
        function Bandwith2HzKnobValueChanged(app, event)
            app.bandWidth2 = app.Bandwith2HzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: Gain2dBKnob
        function Gain2dBKnobValueChanged(app, event)
            app.gain2 = app.Gain2dBKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: CenterFreq3HzKnob
        function CenterFreq3HzKnobValueChanged(app, event)
            app.frequenciaCentral3 = app.CenterFreq3HzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: Gain3dBKnob
        function Gain3dBKnobValueChanged(app, event)
            app.gain3 = app.Gain3dBKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Value changed function: Bandwith3HzKnob
        function Bandwith3HzKnobValueChanged(app, event)
            app.bandWidth3 = app.Bandwith3HzKnob.Value;
            getCoeficients(app);
            filterEqu(app);
            plotSignals(app);
        end

        % Callback function
        function MenuDropDownValueChanged(app, event)
        % No sabem com borrar-la
        end

        % Menu selected function: GuardarFitxerMenu
        function GuardarFitxerMenuSelected(app, event)
            audiowrite('audio_filtrat.wav',app.senyalFiltrada,app.fs);
        end

        % Menu selected function: CarregarFitxerMenu
        function CarregarFitxerMenuSelected(app, event)
            [app.audioCarregat,app.fsOriginal] = audioread('audio_amb_soroll_3.wav');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.CarregarFitxerMenu = uimenu(app.FileMenu);
            app.CarregarFitxerMenu.MenuSelectedFcn = createCallbackFcn(app, @CarregarFitxerMenuSelected, true);
            app.CarregarFitxerMenu.Text = 'Carregar Fitxer';
            app.GuardarFitxerMenu = uimenu(app.FileMenu);
            app.GuardarFitxerMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarFitxerMenuSelected, true);
            app.GuardarFitxerMenu.Text = 'Guardar Fitxer';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Frequency[Hz]')
            ylabel(app.UIAxes, 'GaindB')
            zlabel(app.UIAxes, 'Z')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Frequency', 'Time'};
            app.Switch.ItemsData = {'Frequency', 'Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Frequency';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Original', 'Filtered'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Original';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.GaindBKnob_4Label = uilabel(app.UIFigure);
            app.GaindBKnob_4Label.Text = 'Gain[dB]';
            app.GaindBKnob_1 = uiknob(app.UIFigure, 'continuous');
            app.GaindBKnob_1.Limits = [-90 10];
            app.GaindBKnob_1.ValueChangedFcn = createCallbackFcn(app, @GaindBKnob_1ValueChanged, true);
            app.BandwidthHzKnobLabel = uilabel(app.UIFigure);
            app.BandwidthHzKnobLabel.Text = 'Bandwidth[Hz]';
            app.BandwidthHzKnob = uiknob(app.UIFigure, 'continuous');
            app.BandwidthHzKnob.Limits = [0 2000];
            app.BandwidthHzKnob.ValueChangedFcn = createCallbackFcn(app, @BandwidthHzKnobValueChanged, true);
            app.CenterFreq2HzKnobLabel = uilabel(app.UIFigure);
            app.CenterFreq2HzKnobLabel.Text = 'Center Freq2[Hz]';
            app.CenterFreq2HzKnob = uiknob(app.UIFigure, 'continuous');
            app.CenterFreq2HzKnob.Limits = [0 5000];
            app.CenterFreq2HzKnob.MajorTicks = [100 1080 2060 3040 4020 5000];
            app.CenterFreq2HzKnob.ValueChangedFcn = createCallbackFcn(app, @CenterFreq2HzKnobValueChanged, true);
            app.Bandwith2HzKnobLabel = uilabel(app.UIFigure);
            app.Bandwith2HzKnobLabel.Text = 'Bandwith2[Hz]';
            app.CenterFreqHzKnobLabel = uilabel(app.UIFigure);
            app.CenterFreqHzKnobLabel.Text = 'Center Freq [Hz]';
            app.CenterFreqHzKnob = uiknob(app.UIFigure, 'continuous');
            app.CenterFreqHzKnob.Limits = [-100 100];
            app.CenterFreqHzKnob.MajorTicks = [-100 -50 0 50 100];
            app.CenterFreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @CenterFreqHzKnobValueChanged, true);
            app.Bandwith2HzKnob = uiknob(app.UIFigure, 'continuous');
            app.Bandwith2HzKnob.Limits = [0 5000];
            app.Bandwith2HzKnob.ValueChangedFcn = createCallbackFcn(app, @Bandwith2HzKnobValueChanged, true);
            app.Gain2dBKnobLabel = uilabel(app.UIFigure);
            app.Gain2dBKnobLabel.Text = 'Gain2[dB]';
            app.Gain2dBKnob = uiknob(app.UIFigure, 'continuous');
            app.Gain2dBKnob.Limits = [-90 10];
            app.Gain2dBKnob.ValueChangedFcn = createCallbackFcn(app, @Gain2dBKnobValueChanged, true);
            app.CenterFreq3HzKnobLabel = uilabel(app.UIFigure);
            app.CenterFreq3HzKnobLabel.Text = 'Center Freq3[Hz]';
            app.CenterFreq3HzKnob = uiknob(app.UIFigure, 'continuous');
            app.CenterFreq3HzKnob.Limits = [0 9000];
            app.CenterFreq3HzKnob.MajorTicks = [100 1080 2060 3040 4020 5000 7000 9000];
            app.CenterFreq3HzKnob.ValueChangedFcn = createCallbackFcn(app, @CenterFreq3HzKnobValueChanged, true);
            app.Gain3dBKnobLabel = uilabel(app.UIFigure);
            app.Gain3dBKnobLabel.Text = 'Gain3[dB]';
            app.Gain3dBKnob = uiknob(app.UIFigure, 'continuous');
            app.Gain3dBKnob.Limits = [-90 10];
            app.Gain3dBKnob.ValueChangedFcn = createCallbackFcn(app, @Gain3dBKnobValueChanged, true);
            app.Bandwith3HzKnobLabel = uilabel(app.UIFigure);
            app.Bandwith3HzKnobLabel.Text = 'Bandwith3[Hz]';
            app.Bandwith3HzKnob = uiknob(app.UIFigure, 'continuous');
            app.Bandwith3HzKnob.Limits = [0 10000];
            app.Bandwith3HzKnob.ValueChangedFcn = createCallbackFcn(app, @Bandwith3HzKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fase2okay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end