classdef lsAudioTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RTlabel                     matlab.ui.control.Label
        BufferLengthSlider          matlab.ui.control.Slider
        bufferlengthSliderLabel     matlab.ui.control.Label
        PpalPanel                   matlab.ui.container.Panel
        ONOFFSwitch                 matlab.ui.control.Switch
        FreqTimeSwitch              matlab.ui.control.Switch
        Label                       matlab.ui.control.Label
        OriginFilterSwitch          matlab.ui.control.Switch
        Switch_3Label               matlab.ui.control.Label
        ActiveEffectsDropDown       matlab.ui.control.DropDown
        ActiveEffectsDropDownLabel  matlab.ui.control.Label
        GraphPanel                  matlab.ui.container.Panel
        EffectAxes                  matlab.ui.control.UIAxes
        SignalAxes                  matlab.ui.control.UIAxes
        ReverbPanel                 matlab.ui.container.Panel
        MixKnob                     matlab.ui.control.Knob
        TimeLabel_2                 matlab.ui.control.Label
        ReverbKnob                  matlab.ui.control.Knob
        TimeLabel                   matlab.ui.control.Label
        EqualizerPanel              matlab.ui.container.Panel
        LPKnob                      matlab.ui.control.Knob
        LowpassfilterLabel          matlab.ui.control.Label
        BPKnob                      matlab.ui.control.Knob
        BLabel                      matlab.ui.control.Label
        HPKnob                      matlab.ui.control.Knob
        HighpassfilterLabel         matlab.ui.control.Label
        BackgroundImage             matlab.ui.control.Image
    end

    
    properties (Access = private)
        deviceReader    % objecte que extreu les trames d àudio del conversor A/D
        deviceWriter    % objecte que envia la trama d àudio amb efecte cap al conversor D/A.
        fs              % freqüència de mostratge dels conversors A/D i D/A
        audio           % vector que conté una trama capturada pel conversor A/D
        audioEfect     % vector que conté una trama processada amb l efecte corresponent per a enviar al conversor D/A 44100
        audiof          % vector que conté l’àudio d’entrada o amb efecte en el domini de la freqüència (per a fer la seva visualització en la segona figura de la interfície)


        % equalitzador
        SOS_LP
        G_LP
        SOS_BP
        G_BP
        SOS_HP
        G_HP
        G_LPF
        G_BPF
        G_HPF
        zieq
        heq_length

        % reverb
        h_reverb
        zirev

        % altres
        suavitzat
    end
    
    methods (Access = private)
        
        function blockbuttons(app,flag)
            app.BPKnob.Enable = flag;
            app.HPKnob.Enable = flag;
            app.LPKnob.Enable = flag;
            app.FreqTimeSwitch.Enable = flag;
            app.OriginFilterSwitch.Enable = flag;
            app.ActiveEffectsDropDown.Enable = flag;
            app.MixKnob.Enable = flag;
            app.ReverbKnob.Enable = flag;
        end
        
        function processAudio(app)
            switch app.ActiveEffectsDropDown.Value
                case 'Equalizer'
                    [H_LP,H_BP,H_HP,H_equalitzador,h_equalitzador,f] = freqResponseGlobalF2(app.G_LPF,app.G_BPF,app.G_HPF,app.SOS_LP,app.G_LP,app.SOS_BP,app.G_BP,app.SOS_HP,app.G_HP,app.fs,app.heq_length);
                    [app.audioEfect,app.zieq] = filter(h_equalitzador,1,app.audio,app.zieq);
                case 'Reverb'
                    [audioReverb,app.zirev] = filter(app.h_reverb,1,app.audio,app.zirev);
                    app.audioEfect = app.audio*(1-app.MixKnob.Value/100) + audioReverb*(app.MixKnob.Value/100)';
                case 'Delay'
            end
         
        end
        
        function freq_temp(app)
            %encarrega de modificar el contingut de les grafiques cada cop
            %que es canvi algun parametre de la interficie
            switch (app.FreqTimeSwitch.Value)
                case "Time"
                        cla(app.SignalAxes);
                        app.SignalAxes.XScale = 'linear';
                        app.SignalAxes.XLabel.String = 'Time [s]';
                        n = ((0:(length(app.audio)-1))) * (1/app.fs);
                        ne = ((0:(length(app.audioEfect)-1))) * (1/app.fs); 
                        if strcmp(app.OriginFilterSwitch,'Filter')
                            plot(app.SignalAxes,ne,app.audioEfect); %audio filtrat
                        else
                            plot(app.SignalAxes,n,app.audio);    %audio normal
                        end
                        axis(app.SignalAxes,'tight');
                        xlabel(app.SignalAxes,'time (s)','Color','w');
                        ylabel(app.SignalAxes,'amplitude','Color','w');
                        set(app.SignalAxes, 'XColor', 'w', 'YColor', 'w');
                        grid(app.SignalAxes,'on');
                        title(app.SignalAxes,'Input time signal','Color','w');
                case "Freq"
                        cla(app.SignalAxes);
                        if strcmp(app.OriginFilterSwitch,'Filter')
                            app.audiof = fft(app.audioEfect); %fem la fft                                
                        else
                            app.audiof = fft(app.audio); %fem la fft
                        end
                        nf = round(app.BufferLengthSlider.Value);
                        f = (0:(nf-1))*app.fs/app.BufferLengthSlider.Value; 
                        app.audiof = app.audiof*app.suavitzat + (1-app.suavitzat)*abs(app.audiof(1:nf)).^2;
                        semilogx(app.SignalAxes,f,10*log10(app.audiof));
                        axis(app.SignalAxes,'tight');
                        xlabel(app.SignalAxes,'frequency (Hz)','Color','w');
                        ylabel(app.SignalAxes,'amplitude (dB)','Color','w');
                        set(app.SignalAxes, 'XColor', 'w', 'YColor', 'w');
                        grid(app.SignalAxes,'on');
                        title(app.SignalAxes,'Input signal spectrum','Color','w');
            end   
        end

        function filterResponseGraph(app)
            switch app.ActiveEffectsDropDown.Value
                case 'Equalizer'
                    % Funció que dibuixa les respostes en freqüència tant dels
                    % filtres com de l'EQ

                    % Calculem els nous guanys dels filtres a partir dels
                    % valors dels knobs

                    [H_LP,H_BP,H_HP,H_equalitzador,h_equalitzador,f] = freqResponseGlobalF2(app.G_LPF,app.G_BPF,app.G_HPF,app.SOS_LP,app.G_LP,app.SOS_BP,app.G_BP,app.SOS_HP,app.G_HP,app.fs,app.heq_length);

                    % Dibuixa la funció de transferència dels filtres i de l'EQ
                    hold(app.EffectAxes, 'off');
                    semilogx(app.EffectAxes,f,20*log10(abs(H_LP)),f,20*log10(abs(H_BP)),f,20*log10(abs(H_HP))); %semilogx fa el eix en logaritmic
                    %xlabel(app.Grafica2,'Frequency (Hz)','Color','w');
                    ylabel(app.EffectAxes,'Magnitude (dB)','Color','w');
                    title(app.EffectAxes,'EQUALIZER Response','Color','w');
                    hold(app.EffectAxes, 'on');
        
                    semilogx(app.EffectAxes,f,20*log10(abs(H_equalitzador)),'k');
                    legend(app.EffectAxes,'Lowpass filter','Bandpass filter','Highpass filter','Equalizer')
                    grid(app.EffectAxes,'on');
                    axis(app.EffectAxes,'tight');
                    ylim(app.EffectAxes,[-30 20])
                case 'Reverb'
                    app.h_reverb = [app.h_reverb zeros(1,5*app.fs-length(app.h_reverb))];
                    t = (0:(app.fs*5-1))/app.fs;
                    hold(app.EffectAxes,'off')
                    set(app.EffectAxes,'XScale','linear');
                    plot(app.EffectAxes,t,app.h_reverb);
                    axis(app.EffectAxes,'tight');
                    xlabel(app.EffectAxes,'time (s)','Color','w');
                    ylabel(app.EffectAxes,'amplitude','Color','w');
                    title(app.EffectAxes,'Reverb impulse response');
            end
        end

        function getCoefficients(app)
            [app.h_reverb,reverb_size] = reverbResponse(app.fs,round(app.ReverbKnob.Value,1));
            app.G_BPF = app.BPKnob.Value;
            app.G_LPF = app.LPKnob.Value;
            app.G_HPF = app.HPKnob.Value;
            filterResponseGraph(app);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.SOS_LP,app.G_LP,app.SOS_BP,app.G_BP,app.SOS_HP,app.G_HP] = retrieveSOS("filtersSOS");
            app.heq_length = 1600;
            app.fs = 44100;
            app.suavitzat = 0.85;
            app.audiof = zeros(round(app.BufferLengthSlider.Value),1);
            getCoefficients(app);
        end

        % Value changed function: FreqTimeSwitch
        function FreqTimeSwitchValueChanged(app, event)
            value = app.FreqTimeSwitch.Value;
            getCoefficients(app);
        end

        % Value changed function: OriginFilterSwitch
        function OriginFilterSwitchValueChanged(app, event)
            value = app.OriginFilterSwitch.Value;
            
        end

        % Value changed function: ONOFFSwitch
        function ONOFFSwitchValueChanged(app, event)
            if strcmp(app.ONOFFSwitch.Value,'ON')
                % Inicialitzem els buffers de la reverb i l'equalitzador
                % per bandes
                app.zirev = zeros(1,max(length(app.h_reverb),1)-1);
                [H_LP,H_BP,H_HP,H_equalitzador,h_equalitzador,f] = freqResponseGlobalF2(app.G_LPF,app.G_BPF,app.G_HPF,app.SOS_LP,app.G_LP,app.SOS_BP,app.G_BP,app.SOS_HP,app.G_HP,app.fs,app.heq_length);
                app.zieq = zeros(1,max(length(h_equalitzador),1)-1);
                % Inicialitzem la comunicació amb els conversors AD i DA, i
                % fixem la freqüència de mostratge a 44100 Hz
                app.deviceReader = audioDeviceReader(app.fs, app.BufferLengthSlider.Value);
                app.deviceWriter = audioDeviceWriter('SampleRate',app.deviceReader.SampleRate);
                % Bloquejem tots els altres controls de la interfície menys
                % l'onoff
                app.blockbuttons(false)
                % Inicializem comptador de temps
                tic
                try
                    while strcmp(app.ONOFFSwitch.Value,'ON')
                        % Llegim una trama del conversor AD
                        app.audio = app.deviceReader();
                        % Processem una trama
                        processAudio(app)
                        % Escrivim la trama processada al conversor DA
                        app.deviceWriter(app.audioEfect);
                        % Dibuixem el senyal d'entrada/sortida en el domini
                        % temporal/frequencial
                        freq_temp(app);
                        % Parem comptador de temps
                        totalTime = toc;
                        tic
                        pause(0.001);
                        % Treiem missatge en la etiqueta de la interfície
                        % RTLabel sobre si el procés va a temps real o no
                        if (totalTime<(app.BufferLengthSlider.Value/app.fs))
                            app.RTlabel.Text = [' Real-Time Frame length = ',num2str(app.BufferLengthSlider.Value/app.fs)];
                        else
                            app.RTlabel.Text = [ 'No Real-Time Frame length = ',num2str(app.BufferLengthSlider.Value/app.fs),' < Proc. Time = ',num2str(totalTime),')'];
                        end
                    end
                catch
                    app.RTlabel.Text = 'Real-time effect stopped';
                end
                % Desbloquejem tots els controls de la interfície
                app.blockbuttons(true)
                % Finalitzem la comunicació amb els conversors AD i DA
                release(app.deviceReader);
                release(app.deviceWriter);
            end
        end

        % Value changed function: ActiveEffectsDropDown
        function ActiveEffectsDropDownValueChanged(app, event)
            value = app.ActiveEffectsDropDown.Value;
            filterResponseGraph(app);
        end

        % Value changed function: HPKnob
        function HPKnobValueChanged(app, event)
            value = app.HPKnob.Value;
            getCoefficients(app)
        end

        % Value changed function: BPKnob
        function BPKnobValueChanged(app, event)
            value = app.BPKnob.Value;
            getCoefficients(app)
        end

        % Value changed function: LPKnob
        function LPKnobValueChanged(app, event)
            value = app.LPKnob.Value;
            getCoefficients(app)
        end

        % Clicked callback: ActiveEffectsDropDown
        function ActiveEffectsDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: ReverbKnob
        function ReverbKnobValueChanged(app, event)
            value = app.ReverbKnob.Value;
            getCoefficients(app)
        end

        % Value changed function: MixKnob
        function MixKnobValueChanged(app, event)
            value = app.MixKnob.Value;
            getCoefficients(app)
        end

        % Value changed function: BufferLengthSlider
        function BufferLengthSliderValueChanged(app, event)
            value = app.BufferLengthSlider.Value;
            getCoefficients(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackgroundImage = uiimage(app.UIFigure);
            app.BackgroundImage.ImageSource = fullfile(pathToMLAPP, 'background2.jpg');
            app.EqualizerPanel = uipanel(app.UIFigure);
            app.EqualizerPanel.Title = 'Equalizer';
            app.HighpassfilterLabel = uilabel(app.EqualizerPanel);
            app.HighpassfilterLabel.Text = 'High pass filter';
            app.HPKnob = uiknob(app.EqualizerPanel, 'continuous');
            app.HPKnob.Limits = [-30 20];
            app.HPKnob.ValueChangedFcn = createCallbackFcn(app, @HPKnobValueChanged, true);
            app.BLabel = uilabel(app.EqualizerPanel);
            app.BLabel.Text = 'Band pass filter';
            app.BPKnob = uiknob(app.EqualizerPanel, 'continuous');
            app.BPKnob.Limits = [-30 20];
            app.BPKnob.ValueChangedFcn = createCallbackFcn(app, @BPKnobValueChanged, true);
            app.LowpassfilterLabel = uilabel(app.EqualizerPanel);
            app.LowpassfilterLabel.Text = 'Low pass filter';
            app.LPKnob = uiknob(app.EqualizerPanel, 'continuous');
            app.LPKnob.Limits = [-30 20];
            app.LPKnob.ValueChangedFcn = createCallbackFcn(app, @LPKnobValueChanged, true);
            app.ReverbPanel = uipanel(app.UIFigure);
            app.ReverbPanel.Title = 'Reverb';
            app.TimeLabel = uilabel(app.ReverbPanel);
            app.TimeLabel.Text = 'Time';
            app.ReverbKnob = uiknob(app.ReverbPanel, 'continuous');
            app.ReverbKnob.Limits = [0.5 5];
            app.ReverbKnob.MajorTicks = [0.5 1.5 2.5 3.5 4.5 5];
            app.ReverbKnob.ValueChangedFcn = createCallbackFcn(app, @ReverbKnobValueChanged, true);
            app.ReverbKnob.MinorTicks = [0.5 0.7 0.9 1.1 1.3 1.5 1.7 1.9 2.1 2.3 2.5 2.7 2.9 3.1 3.3 3.5 3.7 3.9 4.1 4.3 4.5 4.7 5];
            app.ReverbKnob.Value = 0.5;
            app.TimeLabel_2 = uilabel(app.ReverbPanel);
            app.TimeLabel_2.Text = '% Mix';
            app.MixKnob = uiknob(app.ReverbPanel, 'continuous');
            app.MixKnob.ValueChangedFcn = createCallbackFcn(app, @MixKnobValueChanged, true);
            app.GraphPanel = uipanel(app.UIFigure);
            app.SignalAxes = uiaxes(app.GraphPanel);
            title(app.SignalAxes, 'Title')
            xlabel(app.SignalAxes, 'X')
            ylabel(app.SignalAxes, 'Y')
            zlabel(app.SignalAxes, 'Z')
            app.EffectAxes = uiaxes(app.GraphPanel);
            title(app.EffectAxes, 'Title')
            ylabel(app.EffectAxes, 'Magnitude [dB]')
            app.PpalPanel = uipanel(app.UIFigure);
            app.ActiveEffectsDropDownLabel = uilabel(app.PpalPanel);
            app.ActiveEffectsDropDownLabel.Text = 'Active Effects';
            app.ActiveEffectsDropDown = uidropdown(app.PpalPanel);
            app.ActiveEffectsDropDown.Items = {'Equalizer', 'Reverb'};
            app.ActiveEffectsDropDown.ValueChangedFcn = createCallbackFcn(app, @ActiveEffectsDropDownValueChanged, true);
            app.ActiveEffectsDropDown.ClickedFcn = createCallbackFcn(app, @ActiveEffectsDropDownClicked, true);
            app.ActiveEffectsDropDown.Value = 'Equalizer';
            app.Switch_3Label = uilabel(app.PpalPanel);
            app.Switch_3Label.Text = '';
            app.OriginFilterSwitch = uiswitch(app.PpalPanel, 'slider');
            app.OriginFilterSwitch.Items = {'Origin', 'Filter'};
            app.OriginFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @OriginFilterSwitchValueChanged, true);
            app.OriginFilterSwitch.Value = 'Origin';
            app.Label = uilabel(app.PpalPanel);
            app.Label.Text = '';
            app.FreqTimeSwitch = uiswitch(app.PpalPanel, 'slider');
            app.FreqTimeSwitch.Items = {'Freq', 'Time'};
            app.FreqTimeSwitch.ValueChangedFcn = createCallbackFcn(app, @FreqTimeSwitchValueChanged, true);
            app.FreqTimeSwitch.Value = 'Freq';
            app.ONOFFSwitch = uiswitch(app.PpalPanel, 'slider');
            app.ONOFFSwitch.Items = {'ON', 'OFF'};
            app.ONOFFSwitch.ValueChangedFcn = createCallbackFcn(app, @ONOFFSwitchValueChanged, true);
            app.ONOFFSwitch.Value = 'OFF';
            app.bufferlengthSliderLabel = uilabel(app.UIFigure);
            app.bufferlengthSliderLabel.Text = 'buffer length';
            app.BufferLengthSlider = uislider(app.UIFigure);
            app.BufferLengthSlider.Limits = [1024 10240];
            app.BufferLengthSlider.MajorTicks = [];
            app.BufferLengthSlider.ValueChangedFcn = createCallbackFcn(app, @BufferLengthSliderValueChanged, true);
            app.BufferLengthSlider.Value = 1024;
            app.RTlabel = uilabel(app.UIFigure);
            app.RTlabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsAudioTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end