classdef app_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AboutMenu             matlab.ui.container.Menu
        Switch_Show_listen    matlab.ui.control.Switch
        ActiveEffectsLabel    matlab.ui.control.Label
        onoff                 matlab.ui.control.Switch
        Switch_original_filt  matlab.ui.control.Switch
        Image                 matlab.ui.control.Image
        DelayLabel_2          matlab.ui.control.Label
        EchostrengthLabel     matlab.ui.control.Label
        HighpassfilterLabel   matlab.ui.control.Label
        BandpassfilterLabel   matlab.ui.control.Label
        LowpassfilterLabel    matlab.ui.control.Label
        EffectDropDown        matlab.ui.control.DropDown
        ReverbLabel           matlab.ui.control.Label
        DelayLabel            matlab.ui.control.Label
        knob_reverb           matlab.ui.control.Knob
        knob_echo             matlab.ui.control.Knob
        knob_delay            matlab.ui.control.Knob
        Switch_freq_time      matlab.ui.control.Switch
        Label                 matlab.ui.control.Label
        EqualizerLabel        matlab.ui.control.Label
        knob_BPF              matlab.ui.control.Knob
        knob_HPF              matlab.ui.control.Knob
        knob_LPF              matlab.ui.control.Knob
        Image2                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        Grafica1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %declarem totes les variables globals del programa
        %parra acceder a ellas poder app.X
        audio
        audioEfect
        fs
        stop_stream = 0;
        frameLength = 1024;
        fileReader 
        deviceWriter
        zi
        fileWriter
        G_LPF
        G_BPF
        G_HPF
        G_DELAY
        G_ECHO
        G_REVERB
        IIR1_NUM
        IIR1_DEN
        IIR2_NUM
        IIR2_DEN
        IIR3_NUM
        IIR3_DEN
        delay 
        D 
        a
        alpha 
        h_reverb 
        zirev 
        zieq1 
        zieq2 
        zieq3 
        zieq4 
        flagListenNshow = 1;
        origNFilt = 1;
    end
    
    methods (Access = private)
        
        function getCoeficients(app)
            %encarrega d'actualizar els coeficients dels filtres quan es
            %modifica el guany o es cambia de tipos de efecte

            app.G_LPF = db2mag(app.knob_LPF.Value);
            app.G_BPF = db2mag(app.knob_BPF.Value);
            app.G_HPF = db2mag(app.knob_HPF.Value);
            app.G_ECHO = app.knob_echo.Value;
            app.G_DELAY = app.knob_delay.Value;
            app.G_REVERB = app.knob_reverb.Value;
            app.delay = app.G_DELAY; % 0.5s delay  
            app.D = app.delay*app.fs;   %separacio entre deltes
            app.alpha = app.G_ECHO; % echo strength 
            % init de la reverb
            T_reverb = app.G_REVERB;    
            a_reverb = 10^(-3/T_reverb); %s'extreu de la formula
            Lh = round(T_reverb*app.fs);
            app.h_reverb = (a_reverb.^((0:(Lh-1))/app.fs)).*randn(1,Lh);
            app.h_reverb = app.h_reverb/norm(app.h_reverb);
            app.zirev = zeros(1,length(app.h_reverb)-1);

        end

        function processAudio(app)
            %encarrega de modificar el contingut de las graficas cada cop
            %que es cambia algun parametre de la interficie
            getCoeficients(app)
            switch (app.EffectDropDown.Value) %pillem el valor del drop down
                case "Equalizer"
                    
                    %[h1,app.zieq1] = filter(app.IIR1_NUM * app.G_LPF,app.IIR1_DEN,x,app.zieq1);                           
                    %[h2,app.zieq2] = filter(app.IIR2_NUM * app.G_BPF,app.IIR2_DEN,x,app.zieq2);                            
                    %[h3,app.zieq3] = filter(app.IIR3_NUM * app.G_HPF,app.IIR3_DEN,x,app.zieq3);
                    %Ffinal = h1+h2+h3;
                    % [app.audioEfect,app.zieq4] = filter(Ffinal,1,app.audio,app.zieq4);

                    %multiplicamos los coeficientes del filtro con la
                    %ganancia en lineal
                    Resul1_iir = app.IIR1_NUM * app.G_LPF;
                    Resul2_iir = app.IIR2_NUM * app.G_BPF;
                    Resul3_iir = app.IIR3_NUM * app.G_HPF;
                    
                    %filtramos una señal
%                      N = 1000;
%                      x = [1;zeros(N-1,1)]; %creamos una delta

                   [filtrat1,app.zieq1] = filter(Resul1_iir,app.IIR1_DEN,app.audio,app.zieq1);
                   [filtrat2,app.zieq2] = filter(Resul2_iir,app.IIR2_DEN,app.audio,app.zieq2);
                   [filtrat3,app.zieq3] = filter(Resul3_iir,app.IIR3_DEN,app.audio,app.zieq3);
                  
                   app.audioEfect = filtrat1+filtrat2+filtrat3;
                  

                case "Delay"
                    
                    [audioOUT,app.zi] = filter(1,app.a,app.audio,app.zi);
                    audioOUT(audioOUT>1) = 0.98;
                    app.audioEfect = audioOUT;
                    
                case "Reverb"
                    [audioOUT,app.zirev] = filter(app.h_reverb,1,app.audio,app.zirev); 
                    audioOUT(audioOUT>1) = 0.98;
                    app.audioEfect = audioOUT;
            end   
        end

        function freq_temp(app)
            %encarrega de modificar el contingut de les grafiques cada cop
            %que es canvi algun paramtre de la interficie
            if app.flagListenNshow == 0
                switch (app.Switch_freq_time.Value)
                    case "Time"
                        if app.flagListenNshow ==0
                            if app.origNFilt ==0
                                cla(app.Grafica1);
                                app.Grafica1.XScale = 'linear';
                                app.Grafica1.XLabel.String = 'Time [s]';
                                n = ((0:(length(app.audio)-1))) * (1/app.fs);
                                ne = ((0:(length(app.audioEfect)-1))) * (1/app.fs); %nose si esta be
                                plot(app.Grafica1,ne,app.audioEfect,'m'); %audio filtrat
                            else
                                cla(app.Grafica1);
                                app.Grafica1.XScale = 'linear';
                                app.Grafica1.XLabel.String = 'Time [s]'
                                n = ((0:(length(app.audio)-1))) * (1/app.fs);
                                ne = ((0:(length(app.audioEfect)-1))) * (1/app.fs); %nose si esta be
                                plot(app.Grafica1,n,app.audio, 'g');    %audio normal
                                xlim(app.Grafica1,'auto');
                            end
                        end
                    case "Freq"
                        if app.flagListenNshow ==0
                            if app.origNFilt ==0
                                cla(app.Grafica1);  
                                app.Grafica1.XScale = 'log';
                                app.Grafica1.XLabel.String = 'Freq [Hz]';
                                audiof = fft(app.audioEfect); %fem la fft
                                num_mostres = floor(length(audiof) / 2) + 1; %calculem el numero de muestras
                                n = (0:(num_mostres-1)) * app.fs / length(app.audioEfect); 
                                plot(app.Grafica1,n,20*log10(abs(audiof(1:num_mostres))),'m');
                            else
                                cla(app.Grafica1);  
                                app.Grafica1.XScale = 'log';
                                app.Grafica1.XLabel.String = 'Freq [Hz]';
                                audiof = fft(app.audio); %fem la fft
                                num_mostres = floor(length(audiof) / 2) + 1; %calculem el numero de muestras
                                n = (0:(num_mostres-1)) * app.fs / length(app.audio); 
                                plot(app.Grafica1,n,20*log10(abs(audiof(1:num_mostres))),'g');
                                hold(app.Grafica1);
                                %axis(app.Grafica1,[10 7000 0 100]);
                            end
                        end
                end   
            end
        end

        
        function  blockbuttons(app,flag)
            app.EffectDropDown.Enable = flag;
            app.Switch_Show_listen.Enable = flag;
            app.Switch_freq_time.Enable = flag;
            app.Switch_original_filt.Enable = flag;
            app.knob_BPF.Enable = flag;
            app.knob_HPF.Enable = flag;
            app.knob_LPF.Enable = flag;
            app.knob_echo.Enable = flag;
            app.knob_delay.Enable = flag;
            app.knob_reverb.Enable = flag;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('filtersIIR_order4.mat','f1_Den','f1_Num','f2_Den','f2_Num','f3_Den','f3_Num');

            app.IIR1_NUM = f1_Num;
            app.IIR1_DEN = f1_Den;
            app.IIR2_NUM = f2_Num;
            app.IIR2_DEN = f2_Den;
            app.IIR3_NUM = f3_Num;
            app.IIR3_DEN = f3_Den;
                        
            app.a  = [1,zeros(1,round(app.D)),-app.alpha];
            app.zi = zeros(1,length(app.a)-1);
            
            app.zieq1 = zeros(1,4);
            app.zieq2 = zeros(1,4);
            app.zieq3 = zeros(1,4);
            app.zieq4 = zeros(1,1000-1);
            getCoeficients(app)
            disp('Iniciando programa...')
             
        end

        % Value changed function: knob_LPF
        function knob_LPFValueChanged(app, event)
            value = app.knob_LPF.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: knob_BPF
        function knob_BPFValueChanged(app, event)
            value = app.knob_BPF.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: knob_HPF
        function knob_HPFValueChanged(app, event)
            value = app.knob_HPF.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Callback function
        function button_stopButtonPushed(app, event)
            app.stop_stream =1 
            
        end

        % Value changed function: Switch_original_filt
        function Switch_original_filtValueChanged(app, event)
            value = app.Switch_original_filt.Value;
            if value== 'O'
                app.origNFilt = 1;
            else
                app.origNFilt = 0;
            end
            
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'Creat per:' ; 'Carla Ristol Torres' ; 'Mireia Canellas Galceran'})
        end

        % Value changed function: knob_echo
        function knob_echoValueChanged(app, event)
            value = app.knob_echo.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: knob_delay
        function knob_delayValueChanged(app, event)
            value = app.knob_delay.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: knob_reverb
        function knob_reverbValueChanged(app, event)
            value = app.knob_reverb.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: EffectDropDown
        function EffectDropDownValueChanged(app, event)
            value = app.EffectDropDown.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: Switch_freq_time
        function Switch_freq_timeValueChanged(app, event)
            value = app.Switch_freq_time.Value;
            getCoeficients(app)
            app.freq_temp()
            %esollirEfecte(app) 
            
        end

        % Value changed function: onoff
        function onoffValueChanged(app, event)
            app.fileReader = audioDeviceReader;
            app.deviceWriter = audioDeviceWriter('SampleRate',app.fileReader.SampleRate);
            %app.fileWriter = dsp.AudioFileWriter('SampleRate',app.fileReader.SampleRate);
            app.fs = app.fileReader.SampleRate;
            app.blockbuttons(false)
            while 1
                app.audio = app.fileReader();
                processAudio(app)
                if(app.flagListenNshow) == 1
                    app.deviceWriter(app.audioEfect);
                else
                    app.freq_temp
                end
                %app.fileWriter(app.audioEfect);
                %app.freq_temp()
                pause(0.001)
                if(app.onoff.Value(2) == 'F')
                   app.blockbuttons(true)
                   break;
                end
            end
        end

        % Value changed function: Switch_Show_listen
        function Switch_Show_listenValueChanged(app, event)
            value = app.Switch_Show_listen.Value;
            if value(1) == 'L'
                app.flagListenNshow = 1;
            else
                app.flagListenNshow = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'AboutMenu';
            app.Grafica1 = uiaxes(app.UIFigure);
            xlabel(app.Grafica1, 'Time (s)')
            ylabel(app.Grafica1, 'Gain[dB]')
            zlabel(app.Grafica1, 'Z')
            app.Grafica1.XTickLabel = '';
            app.Grafica1.YTickLabel = '';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Captura de pantalla 2021-12-15 a las 18.30.11.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Captura de pantalla 2021-12-15 a las 18.29.56.png';
            app.knob_LPF = uiknob(app.UIFigure, 'continuous');
            app.knob_LPF.Limits = [-60 10];
            app.knob_LPF.ValueChangedFcn = createCallbackFcn(app, @knob_LPFValueChanged, true);
            app.knob_HPF = uiknob(app.UIFigure, 'continuous');
            app.knob_HPF.Limits = [-60 10];
            app.knob_HPF.ValueChangedFcn = createCallbackFcn(app, @knob_HPFValueChanged, true);
            app.knob_BPF = uiknob(app.UIFigure, 'continuous');
            app.knob_BPF.Limits = [-60 10];
            app.knob_BPF.ValueChangedFcn = createCallbackFcn(app, @knob_BPFValueChanged, true);
            app.EqualizerLabel = uilabel(app.UIFigure);
            app.EqualizerLabel.Text = 'Equalizer';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Switch_freq_time = uiswitch(app.UIFigure, 'slider');
            app.Switch_freq_time.Items = {'Freq', 'Time'};
            app.Switch_freq_time.ValueChangedFcn = createCallbackFcn(app, @Switch_freq_timeValueChanged, true);
            app.Switch_freq_time.Value = 'Freq';
            app.knob_delay = uiknob(app.UIFigure, 'continuous');
            app.knob_delay.Limits = [0.1 2];
            app.knob_delay.MajorTicks = [0.1 0.4 0.7 1 1.3 1.6 2];
            app.knob_delay.ValueChangedFcn = createCallbackFcn(app, @knob_delayValueChanged, true);
            app.knob_delay.Value = 0.1;
            app.knob_echo = uiknob(app.UIFigure, 'continuous');
            app.knob_echo.Limits = [0 0.99];
            app.knob_echo.ValueChangedFcn = createCallbackFcn(app, @knob_echoValueChanged, true);
            app.knob_reverb = uiknob(app.UIFigure, 'continuous');
            app.knob_reverb.Limits = [0.5 5];
            app.knob_reverb.MajorTicks = [0.5 1.5 2.5 3.5 4.5 5];
            app.knob_reverb.ValueChangedFcn = createCallbackFcn(app, @knob_reverbValueChanged, true);
            app.knob_reverb.Value = 0.5;
            app.DelayLabel = uilabel(app.UIFigure);
            app.DelayLabel.Text = 'Delay';
            app.ReverbLabel = uilabel(app.UIFigure);
            app.ReverbLabel.Text = 'Reverb';
            app.EffectDropDown = uidropdown(app.UIFigure);
            app.EffectDropDown.Items = {'Equalizer', 'Delay', 'Reverb'};
            app.EffectDropDown.ValueChangedFcn = createCallbackFcn(app, @EffectDropDownValueChanged, true);
            app.EffectDropDown.Value = 'Equalizer';
            app.LowpassfilterLabel = uilabel(app.UIFigure);
            app.LowpassfilterLabel.Text = 'Low pass filter';
            app.BandpassfilterLabel = uilabel(app.UIFigure);
            app.BandpassfilterLabel.Text = 'Band pass filter';
            app.HighpassfilterLabel = uilabel(app.UIFigure);
            app.HighpassfilterLabel.Text = 'High pass filter';
            app.EchostrengthLabel = uilabel(app.UIFigure);
            app.EchostrengthLabel.Text = 'Echo strength';
            app.DelayLabel_2 = uilabel(app.UIFigure);
            app.DelayLabel_2.Text = 'Delay';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Captura de pantalla 2021-12-15 a las 18.23.33.png';
            app.Switch_original_filt = uiswitch(app.UIFigure, 'slider');
            app.Switch_original_filt.Items = {'Origi', 'Filt'};
            app.Switch_original_filt.ValueChangedFcn = createCallbackFcn(app, @Switch_original_filtValueChanged, true);
            app.Switch_original_filt.Value = 'Origi';
            app.onoff = uiswitch(app.UIFigure, 'slider');
            app.onoff.Items = {'ON', 'OFF'};
            app.onoff.ValueChangedFcn = createCallbackFcn(app, @onoffValueChanged, true);
            app.onoff.Value = 'OFF';
            app.ActiveEffectsLabel = uilabel(app.UIFigure);
            app.ActiveEffectsLabel.Text = 'Active Effects';
            app.Switch_Show_listen = uiswitch(app.UIFigure, 'slider');
            app.Switch_Show_listen.Items = {'Show', 'Listen'};
            app.Switch_Show_listen.ValueChangedFcn = createCallbackFcn(app, @Switch_Show_listenValueChanged, true);
            app.Switch_Show_listen.Value = 'Listen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end