classdef app_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AboutMenu                matlab.ui.container.Menu
        RTlabel                  matlab.ui.control.Label
        BufferLengthSlider       matlab.ui.control.Slider
        BufferLengthSliderLabel  matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        ActiveEffectLabel        matlab.ui.control.Label
        onoff                    matlab.ui.control.Switch
        Switch_original_filt     matlab.ui.control.Switch
        EffectDropDown           matlab.ui.control.DropDown
        Switch_freq_time         matlab.ui.control.Switch
        Panel_2                  matlab.ui.container.Panel
        MixLabel                 matlab.ui.control.Label
        knob_reverb_Mix          matlab.ui.control.Knob
        ReverbTimeLabel          matlab.ui.control.Label
        ReverbLabel              matlab.ui.control.Label
        knob_reverb_Time         matlab.ui.control.Knob
        Panel                    matlab.ui.container.Panel
        HPFGaindBLabel           matlab.ui.control.Label
        BPFGaindBLabel           matlab.ui.control.Label
        LPFGaindBLabel           matlab.ui.control.Label
        EqualizerLabel           matlab.ui.control.Label
        knob_BPF                 matlab.ui.control.Knob
        knob_HPF                 matlab.ui.control.Knob
        knob_LPF                 matlab.ui.control.Knob
        Image                    matlab.ui.control.Image
        Grafica2                 matlab.ui.control.UIAxes
        Grafica1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %declarem totes les variables globals del programa
        %parra acceder a ellas poder app.X
        audio
        audioEfect
        fs
        
        deviceReader 
        deviceWriter

        % Paràmetres de l'Equalizer
        % Els guanys lineals dels filtres SOS originals
        G_LP
        G_BP
        G_HP
        % Els guanys lineals dels knobs dels filtres
        G_LPF
        G_BPF
        G_HPF
        SOS_LP
        SOS_BP
        SOS_HP
        zieq

        % Paràmetres de la Reverb
        alpha 
        h_reverb 
        zirev 

        audiof      % Espectre de l'audio d'entrada o amb efecte
        lambda      % Factor de memòria pel càlcul de l'espectre
        origNFilt = 1;
    end
    
    methods (Access = private)
        
        function getCoeficients(app)
            %encarrega d'actualizar els coeficients dels filtres quan es
            %modifica el guany o es cambia de tipos de efecte

            app.G_LPF = db2mag(app.knob_LPF.Value);
            app.G_BPF = db2mag(app.knob_BPF.Value);
            app.G_HPF = db2mag(app.knob_HPF.Value);
            
            % init de la reverb
            T_reverb = app.knob_reverb_Time.Value;    
            a_reverb = 10^(-3/T_reverb); %s'extreu de la formula
            Lh = round(T_reverb*app.fs);
            app.h_reverb = (a_reverb.^((0:(Lh-1))/app.fs)).*randn(1,Lh);
            app.h_reverb = app.h_reverb/norm(app.h_reverb);
        end

        function processAudio(app)
            %encarrega de modificar el contingut de las graficas cada cop
            %que es cambia algun parametre de la interficie
            getCoeficients(app)
            switch (app.EffectDropDown.Value) %pillem el valor del drop down
                case "Equalizer"
                    % Calculem la resposta impulsional de l'equalitzador a partir dels
                    % valors dels knobs
                    [h] = SOScomp(app);
                    
                    % Filtrem el senyal
                    [app.audioEfect,app.zieq] = filter(h,1,app.audio,app.zieq);
                    
                case "Reverb"
                    [audioOUT,app.zirev] = filter(app.h_reverb,1,app.audio,app.zirev); 
                    app.audioEfect = app.audio*(1-app.knob_reverb_Mix.Value/100) + (app.knob_reverb_Mix.Value/100)*audioOUT; 
            end   
        end

        function freq_temp(app)
            %encarrega de modificar el contingut de les grafiques cada cop
            %que es canvi algun paramtre de la interficie
                switch (app.Switch_freq_time.Value)
                    case "Time"
                            cla(app.Grafica1);
                            app.Grafica1.XScale = 'linear';
                            app.Grafica1.XLabel.String = 'Time [s]';
                            n = ((0:(length(app.audio)-1))) * (1/app.fs);
                            ne = ((0:(length(app.audioEfect)-1))) * (1/app.fs); 
                            if app.origNFilt ==0
                                plot(app.Grafica1,ne,app.audioEfect,'m'); %audio filtrat
                            else
                                plot(app.Grafica1,n,app.audio, 'g');    %audio normal
                            end
                            axis(app.Grafica1,'tight');
                            xlabel(app.Grafica1,'time (s)','Color','w');ylabel(app.Grafica1,'amplitude','Color','w');
                            set(app.Grafica1, 'XColor', 'w', 'YColor', 'w');
                            grid(app.Grafica1,'on');
                            title(app.Grafica1,'Input time signal','Color','w');
                    case "Freq"
                            if app.origNFilt ==0
                                audiof = fft(app.audioEfect); %fem la fft                                
                            else
                                audiof = fft(app.audio); %fem la fft
                            end
                            nf = round(app.BufferLengthSlider.Value/2);
                            f = (0:(nf-1))*app.fs/app.BufferLengthSlider.Value; 
                            app.audiof = app.audiof*app.lambda + (1-app.lambda)*abs(audiof(1:nf)).^2;
                            semilogx(app.Grafica1,f,20*log10(app.audiof),'m');axis(app.Grafica1,'tight');
                            xlabel(app.Grafica1,'frequency (Hz)','Color','w');ylabel(app.Grafica1,'amplitude (dB)','Color','w');
                            set(app.Grafica1, 'XColor', 'w', 'YColor', 'w');
                            grid(app.Grafica1,'on');
                            title(app.Grafica1,'Input signal spectrum','Color','w');
                end   
        end

        
        function  blockbuttons(app,flag)
            app.EffectDropDown.Enable = flag;
            app.Switch_freq_time.Enable = flag;
            app.Switch_original_filt.Enable = flag;
            app.knob_BPF.Enable = flag;
            app.knob_HPF.Enable = flag;
            app.knob_LPF.Enable = flag;
            %app.knob_echo.Enable = flag;
            %app.knob_delay.Enable = flag;
            app.knob_reverb_Mix.Enable = flag;
            app.knob_reverb_Time.Enable = flag;
        end

        function [h,h1,h2,h3] = SOScomp(app)
            % Calcula la resposta impulsional global de 1024 punts de
            % l'equalitzador

            % Calculem els nous guanys dels filtres a partir dels
            % valors dels knobs
            G1=app.G_LPF.*prod(app.G_LP);
            G2=app.G_BPF.*prod(app.G_BP);
            G3=app.G_HPF.*prod(app.G_HP);

            % Actualitzem les estructures SOS a partir dels guanys
            SOS_LP = app.SOS_LP;
            SOS_BP = app.SOS_BP;
            SOS_HP = app.SOS_HP;
            SOS_LP(1,1:3) = app.SOS_LP(1,1:3)*G1;
            SOS_BP(1,1:3) = app.SOS_BP(1,1:3)*G2;
            SOS_HP(1,1:3) = app.SOS_HP(1,1:3)*G3;

            % Calculem les respostes impulsionals de cada filtre i la
            % global
            N = 1024; %numero prou elevat per poder pillar mostres
            delta = [1;zeros(N-1,1)]; %posem vector a tot 0 per fer delta i una a 1
            
            h1=sosfilt(SOS_LP,delta);
            h2=sosfilt(SOS_BP,delta);
            h3=sosfilt(SOS_HP,delta);
            h = h1+h2+h3;   % Resposta impulsional del sistema global
        end

        function grafica2(app)
            switch app.EffectDropDown.Value
                case 'Equalizer'
                    % Funció que dibuixa les respostes en freqüència tant dels
                    % filtres com de l'EQ
        
                    % Calculem els nous guanys dels filtres a partir dels
                    % valors dels knobs
                    [h,h1,h2,h3] = SOScomp(app);
                
                    % Dibuixa la funció de transferència dels filtres i de l'EQ
                    nfft = 2048;
                    [H1,f]=freqz(h1,1,nfft,app.fs);  %w corresponde a la fase - 30024 es la resolucio, i ens interesa tenir una resolucio prou alta
                    [H2,f]=freqz(h2,1,nfft,app.fs);  %w corresponde a la fase - 30024 es la resolucio, i ens interesa tenir una resolucio prou alta
                    [H3,f]=freqz(h3,1,nfft,app.fs);  %w corresponde a la fase - 30024 es la resolucio, i ens interesa tenir una resolucio prou alta
                    hold(app.Grafica2, 'off');
                    semilogx(app.Grafica2,f,20*log10(abs(H1)),f,20*log10(abs(H2)),f,20*log10(abs(H3))); %semilogx fa el eix en logaritmic
                    %xlabel(app.Grafica2,'Frequency (Hz)','Color','w');
                    ylabel(app.Grafica2,'Magnitude (dB)','Color','w');
                    title(app.Grafica2,'EQUALIZER Response','Color','w');
                    hold(app.Grafica2, 'on');
        
                    [H,f]=freqz(h,1,nfft,app.fs);  %w corresponde a la fase
                    semilogx(app.Grafica2,f,20*log10(abs(H)),'k');
                    legend(app.Grafica2,'Lowpass filter','Bandpass filter','Highpass filter','Equalizer')
                    grid(app.Grafica2,'on'); axis(app.Grafica2,'tight');ylim(app.Grafica2,[-30 20])
                case 'Reverb'
                    h_reverb = [app.h_reverb zeros(1,5*app.fs-length(app.h_reverb))];
                    t = (0:(app.fs*5-1))/app.fs;
                    hold (app.Grafica2,'off')
                    set(app.Grafica2,'XScale','linear');
                    plot(app.Grafica2,t,h_reverb);axis(app.Grafica2,'tight');
                    xlabel(app.Grafica2,'time (s)','Color','w');ylabel(app.Grafica2,'amplitude','Color','w');
                    title(app.Grafica2,'Reverb impulse response');
                    legend('');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('filtersSOS.mat');

            app.SOS_LP = SOS_LP;
            app.SOS_BP = SOS_BP;
            app.SOS_HP = SOS_HP;
            app.G_LP = G_LP;
            app.G_BP = G_BP;
            app.G_HP = G_HP;
                        
            %app.a  = [1,zeros(1,round(app.D)),-app.alpha];
            %app.zi = zeros(1,length(app.a)-1);
            
            app.zieq = zeros(1,1023);
            app.fs = 44100;
            app.audiof = zeros(round(app.BufferLengthSlider.Value/2),1);
            app.lambda = 0.8;

            getCoeficients(app)
            disp('Iniciando programa...')
             
        end

        % Value changed function: knob_LPF
        function knob_LPFValueChanged(app, event)
            value = app.knob_LPF.Value;
            getCoeficients(app)
            grafica2(app)
        end

        % Value changed function: knob_BPF
        function knob_BPFValueChanged(app, event)
            value = app.knob_BPF.Value;
            getCoeficients(app)
            grafica2(app)
        end

        % Value changed function: knob_HPF
        function knob_HPFValueChanged(app, event)
            value = app.knob_HPF.Value;
            getCoeficients(app)
            grafica2(app)
        end

        % Callback function
        function button_stopButtonPushed(app, event)

        end

        % Value changed function: Switch_original_filt
        function Switch_original_filtValueChanged(app, event)
            value = app.Switch_original_filt.Value;
            if value== 'O'
                app.origNFilt = 1;
            else
                app.origNFilt = 0;
            end
            
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'Creat per:' ; 'Carla Ristol Torres' ; 'Mireia Canellas Galceran'})
        end

        % Callback function
        function knob_echoValueChanged(app, event)
            value = app.knob_echo.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Callback function
        function knob_delayValueChanged(app, event)
            value = app.knob_delay.Value;
            getCoeficients(app)
            %esollirEfecte(app) 
            
        end

        % Value changed function: knob_reverb_Time
        function knob_reverb_TimeValueChanged(app, event)
            value = app.knob_reverb_Time.Value;
            getCoeficients(app)
            grafica2(app);
        end

        % Value changed function: EffectDropDown
        function EffectDropDownValueChanged(app, event)
            value = app.EffectDropDown.Value;
            getCoeficients(app)
            grafica2(app);
        end

        % Value changed function: Switch_freq_time
        function Switch_freq_timeValueChanged(app, event)
            value = app.Switch_freq_time.Value;
            getCoeficients(app)
            app.freq_temp()
            %esollirEfecte(app) 
            
        end

        % Value changed function: onoff
        function onoffValueChanged(app, event)
            if strcmp(app.onoff.Value,'ON')
                app.zirev = zeros(1,length(app.h_reverb)-1);
                app.deviceReader = audioDeviceReader(app.fs, app.BufferLengthSlider.Value);
                app.deviceWriter = audioDeviceWriter('SampleRate',app.deviceReader.SampleRate);
                app.blockbuttons(false)
                tic
                try
                    while strcmp(app.onoff.Value,'ON')
    %                    app.onoff.Value
                        app.audio = app.deviceReader();
                        processAudio(app)
                        app.deviceWriter(app.audioEfect);
                        freq_temp(app);
    
                        totalTime = toc;
                        tic
                        pause(0.001);
                        if (totalTime<(app.BufferLengthSlider.Value/app.fs))
                             app.RTlabel.Text = ['      Real-Time     Frame length = ',num2str(app.BufferLengthSlider.Value/app.fs)];
                        else
                            app.RTlabel.Text = [ 'No Real-Time     Frame length = ',num2str(app.BufferLengthSlider.Value/app.fs),' < Proc. Time = ',num2str(totalTime),')'];
                        end
                    end
                catch
                    app.RTlabel.Text = ['Real-time effect stopped'];
                end
                app.blockbuttons(true)
                release(app.deviceReader);
                release(app.deviceWriter);
            end
        end

        % Callback function: not associated with a component
        function Switch_Show_listenValueChanged(app, event)

        end

        % Value changed function: BufferLengthSlider
        function BufferLengthSliderValueChanged(app, event)
            value = app.BufferLengthSlider.Value;
            app.BufferLengthSlider.Value = round(value);
            app.audiof = zeros(round(app.BufferLengthSlider.Value/2),1);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'AboutMenu';
            app.Grafica1 = uiaxes(app.UIFigure);
            xlabel(app.Grafica1, 'Time (s)')
            ylabel(app.Grafica1, 'Gain[dB]')
            zlabel(app.Grafica1, 'Z')
            app.Grafica1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Grafica1.XTickLabel = '';
            app.Grafica1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Grafica1.YTickLabel = '';
            app.Grafica2 = uiaxes(app.UIFigure);
            title(app.Grafica2, 'Title')
            xlabel(app.Grafica2, 'X')
            ylabel(app.Grafica2, 'Y')
            zlabel(app.Grafica2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Captura de pantalla 2021-12-15 a las 18.23.33.png';
            app.Panel = uipanel(app.UIFigure);
            app.knob_LPF = uiknob(app.Panel, 'continuous');
            app.knob_LPF.Limits = [-30 20];
            app.knob_LPF.ValueChangedFcn = createCallbackFcn(app, @knob_LPFValueChanged, true);
            app.knob_HPF = uiknob(app.Panel, 'continuous');
            app.knob_HPF.Limits = [-30 20];
            app.knob_HPF.ValueChangedFcn = createCallbackFcn(app, @knob_HPFValueChanged, true);
            app.knob_BPF = uiknob(app.Panel, 'continuous');
            app.knob_BPF.Limits = [-30 20];
            app.knob_BPF.ValueChangedFcn = createCallbackFcn(app, @knob_BPFValueChanged, true);
            app.EqualizerLabel = uilabel(app.Panel);
            app.EqualizerLabel.Text = 'Equalizer';
            app.LPFGaindBLabel = uilabel(app.Panel);
            app.LPFGaindBLabel.Text = 'LPF Gain (dB)';
            app.BPFGaindBLabel = uilabel(app.Panel);
            app.BPFGaindBLabel.Text = 'BPF Gain (dB)';
            app.HPFGaindBLabel = uilabel(app.Panel);
            app.HPFGaindBLabel.Text = 'HPF Gain (dB)';
            app.Panel_2 = uipanel(app.UIFigure);
            app.knob_reverb_Time = uiknob(app.Panel_2, 'continuous');
            app.knob_reverb_Time.Limits = [0.5 5];
            app.knob_reverb_Time.MajorTicks = [0.5 1.5 2.5 3.5 4.5 5];
            app.knob_reverb_Time.ValueChangedFcn = createCallbackFcn(app, @knob_reverb_TimeValueChanged, true);
            app.knob_reverb_Time.Value = 0.5;
            app.ReverbLabel = uilabel(app.Panel_2);
            app.ReverbLabel.Text = 'Reverb';
            app.ReverbTimeLabel = uilabel(app.Panel_2);
            app.ReverbTimeLabel.Text = 'Reverb. Time';
            app.knob_reverb_Mix = uiknob(app.Panel_2, 'continuous');
            app.knob_reverb_Mix.MajorTicks = [0 20 40 60 80 100];
            app.knob_reverb_Mix.Value = 50;
            app.MixLabel = uilabel(app.Panel_2);
            app.MixLabel.Text = '% Mix';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Switch_freq_time = uiswitch(app.Panel_3, 'slider');
            app.Switch_freq_time.Items = {'Freq', 'Time'};
            app.Switch_freq_time.ValueChangedFcn = createCallbackFcn(app, @Switch_freq_timeValueChanged, true);
            app.Switch_freq_time.Value = 'Freq';
            app.EffectDropDown = uidropdown(app.Panel_3);
            app.EffectDropDown.Items = {'Equalizer', 'Reverb'};
            app.EffectDropDown.ValueChangedFcn = createCallbackFcn(app, @EffectDropDownValueChanged, true);
            app.EffectDropDown.Value = 'Equalizer';
            app.Switch_original_filt = uiswitch(app.Panel_3, 'slider');
            app.Switch_original_filt.Items = {'Origi', 'Filt'};
            app.Switch_original_filt.ValueChangedFcn = createCallbackFcn(app, @Switch_original_filtValueChanged, true);
            app.Switch_original_filt.Value = 'Origi';
            app.onoff = uiswitch(app.Panel_3, 'slider');
            app.onoff.Items = {'ON', 'OFF'};
            app.onoff.ValueChangedFcn = createCallbackFcn(app, @onoffValueChanged, true);
            app.onoff.Value = 'OFF';
            app.ActiveEffectLabel = uilabel(app.Panel_3);
            app.ActiveEffectLabel.Text = 'Active Effect';
            app.BufferLengthSliderLabel = uilabel(app.UIFigure);
            app.BufferLengthSliderLabel.Text = 'Buffer Length';
            app.BufferLengthSlider = uislider(app.UIFigure);
            app.BufferLengthSlider.Limits = [1024 10240];
            app.BufferLengthSlider.ValueChangedFcn = createCallbackFcn(app, @BufferLengthSliderValueChanged, true);
            app.BufferLengthSlider.Value = 1024;
            app.RTlabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end